classdef line_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabelerUIFigure   matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        UIAxes            matlab.ui.control.UIAxes
        GridLayoutBottom  matlab.ui.container.GridLayout
        StatusLabel       matlab.ui.control.Label
        PREVButton        matlab.ui.control.Button
        NEXTButton        matlab.ui.control.Button
        SaveButton        matlab.ui.control.Button
        PLUSButton        matlab.ui.control.Button
        AHECheckBox       matlab.ui.control.CheckBox
        middlemouseLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        rois % region of interests
        LF % light field
        savePath % path to store rois
    end
    
    properties (Access = private)
        curView % current view
        numViews % number of views
        numLabels % number of labels
        himpolys % handles to image polygons
        useAHE % display images with adaptive contrast applied!
        LabelGrid
        LabelGridLabels
        LabelGridButtons
    end
    
    methods (Access = private)
        
                
        function updateLabels(app)
            if ~isempty(app.himpolys)
                for i = 1:length(app.himpolys)
                    app.rois{app.curView}{i} = app.himpolys{i}.Position;
                end
            end
        end
        
        function redraw(app)
            img = double(app.LF(:,:,:,:,app.curView));
            img( img < 0 ) = 0.0; % clip!
            img = ( img - min(img(:)) ) ./ ...
                ( max(img(:))- min(img(:)) );
            cla( app.UIAxes );
            if( app.useAHE )
                for ic = 1:size(img,3)
                    img(:,:,ic) = adapthisteq( img(:,:,ic), 'NBins', 512 );
                end
            end

            imagesc(app.UIAxes, 'CData', img, [0 1]); 
            
           
            % draw polygons
            nl = length(app.rois{app.curView});
            app.himpolys={};
            for i = 1:nl
                app.himpolys{i} = drawpolygon( 'Parent', app.UIAxes, 'Position', app.rois{app.curView}{i} );
                addlistener(app.himpolys{i},'ROIClicked',createCallbackFcn(app, @ClickRoiEvent, true));
            end
            
            % display some info
            app.StatusLabel.Text = [num2str(app.curView) '/' num2str(app.numViews) ' (BBs ' num2str(nl) ')' ];
            drawnow;
            
        end
        
        % Callback function
        function ClickRoiEvent(app, event)
            %disp(event);
            % deltete with middle button
            if( strcmpi( event.SelectionType,'middle') && strcmpi( event.EventName,'ROIClicked')  && strcmpi( event.SelectedPart, 'face' ) )
                nl = length(app.rois{app.curView});
                for i = 1:nl
                    if app.himpolys{i} == event.Source
                        app.himpolys{i}.Visible = 'off';
                        delete app.himpolys{i}
                        app.himpolys(i) = [];
                        app.rois{app.curView}(i) = [];
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, LF, rois, savePath)
            app.rois = rois;
            app.himpolys={};
            app.savePath = savePath;
            app.useAHE = false;
            
            if iscell(LF)
                LFcell = LF;
                LF = LFcell{1};
                LF = cat(5,LFcell{:});
            end
            app.LF = LF;
            app.numViews = size(LF,5);
            app.curView = 1;
            app.numLabels = length(app.rois);

            imshow( LF(:,:,:,:,app.curView), [min(LF(:)), max(LF(:))], 'Parent', app.UIAxes, 'XData', [1 size(LF,2)], 'YData', [1 size(LF,1)]);
            app.UIAxes.XLim = [1 size(LF,2)];
            app.UIAxes.YLim = [1 size(LF,1)];
            %app.createLabels();
            %app.createPolygons();
            
            app.redraw();
        end

        % Button pushed function: PREVButton
        function PREVButtonPushed(app, event)
            app.updateLabels();
            app.curView = max(1,app.curView - 1);
            app.redraw();
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.updateLabels();
            app.curView = min(app.numViews,app.curView+1);
            app.redraw();
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Multiselect;
            disp(value);
        end

        % Callback function
        function ButtonValueChanged(app, event)
            app.updateLabels();
            
            numLabels = length(app.rois);
            for i = 1:numLabels
                if(app.LabelGridButtons(i)==event.Source)
                    disp(i);
                    app.rois(i).visible(app.curView) = event.Source.Value;
                end
            end
            
            app.redraw();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.updateLabels();
            
            line_rois = app.rois;
            
            save( app.savePath, 'line_rois' );
        end

        % Button pushed function: PLUSButton
        function PLUSButtonPushed(app, event)
            % add a new roi
            app.himpolys{length(app.himpolys)+1} = drawpolygon( 'Parent', app.UIAxes );
        end

        % Value changed function: AHECheckBox
        function AHECheckBoxValueChanged(app, event)
            app.useAHE = app.AHECheckBox.Value;
            app.updateLabels();
            app.redraw();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabelerUIFigure = uifigure('Visible', 'off');
            app.LabelerUIFigure.Name = 'Labeler';
            app.GridLayout = uigridlayout(app.LabelerUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.GridLayoutBottom = uigridlayout(app.GridLayout);
            app.StatusLabel = uilabel(app.GridLayoutBottom);
            app.PREVButton = uibutton(app.GridLayoutBottom, 'push');
            app.PREVButton.ButtonPushedFcn = createCallbackFcn(app, @PREVButtonPushed, true);
            app.PREVButton.Text = 'PREV';
            app.NEXTButton = uibutton(app.GridLayoutBottom, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.SaveButton = uibutton(app.GridLayoutBottom, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PLUSButton = uibutton(app.GridLayoutBottom, 'push');
            app.PLUSButton.ButtonPushedFcn = createCallbackFcn(app, @PLUSButtonPushed, true);
            app.PLUSButton.Text = '+';
            app.AHECheckBox = uicheckbox(app.GridLayoutBottom);
            app.AHECheckBox.ValueChangedFcn = createCallbackFcn(app, @AHECheckBoxValueChanged, true);
            app.AHECheckBox.Text = 'AHE';
            app.middlemouseLabel = uilabel(app.GridLayoutBottom);
            app.middlemouseLabel.Text = '- (middle mouse)';
            % Show the figure after all components are created
            app.LabelerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = line_labeler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabelerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabelerUIFigure)
        end
    end
end